classdef timestable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimesTablesUIFigure      matlab.ui.Figure
        CloseButton              matlab.ui.control.Button
        OKButton                 matlab.ui.control.Button
        WhatisLabel              matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        Question                 matlab.ui.control.Label
        Score                    matlab.ui.control.Label
        ScoreLabel               matlab.ui.control.Label
        TimesTableDropDown       matlab.ui.control.DropDown
        TimesTableDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Game % Game object
    end
    
    methods (Access = public)
        
        function askNewQuestion(app)
            app.Game.setNewQuestion;
            app.updateUI;
        end
    
        function updateUI(app)
            % Create and set a string that looks like
            % (current times table) X (current question)            
            string = sprintf('%d X %d', ...
                app.Game.CurrentTimesTable, app.Game.CurrentQuestion);            
            app.Question.Text = string;
            
            % Clear current answer:
            app.EditField.Value = '';
            
            % Set score:
            app.Score.Text = num2str(app.Game.NumberCorrectAnswers);
        end    
    
        function setAppPostion(app)
            % Move the UI to the centre of the screen:
            screensize = get(0, 'screensize');
            centreX = floor((screensize(3)-screensize(1))/2);
            centreY = floor((screensize(4)-screensize(2))/2);
            app.TimesTablesUIFigure.Position = [centreX centreY 350 200];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function newGame(app)
            app.setAppPostion;
            app.Game = timesTableGame;
            app.TimesTableDropDown.Value = num2str(app.Game.CurrentTimesTable);
            app.askNewQuestion;            
        end

        % Button pushed function: CloseButton
        function userQuit(app, event)
            app.delete();
        end

        % Button pushed function: OKButton
        function checkAnswer(app, event)
            % game.
            value = app.EditField.Value;
            if app.Game.isAnswerCorrect(value)
                app.Game.incrementScore;
                app.askNewQuestion;
            else
                app.updateUI;
            end
        end

        % Value changed function: TimesTableDropDown
        function timestableChanged(app, event)
            value = app.TimesTableDropDown.Value;
            app.Game.setNewTimesTable(value);
            app.updateUI;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TimesTablesUIFigure = uifigure('Visible', 'off');
            app.TimesTablesUIFigure.Name = 'Times Tables';
            app.TimesTableDropDownLabel = uilabel(app.TimesTablesUIFigure);
            app.TimesTableDropDownLabel.Text = 'Times Table';
            app.TimesTableDropDown = uidropdown(app.TimesTablesUIFigure);
            app.TimesTableDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.TimesTableDropDown.ValueChangedFcn = createCallbackFcn(app, @timestableChanged, true);
            app.TimesTableDropDown.Value = '3';
            app.ScoreLabel = uilabel(app.TimesTablesUIFigure);
            app.ScoreLabel.Text = 'Score';
            app.Score = uilabel(app.TimesTablesUIFigure);
            app.Score.Text = '0';
            app.Question = uilabel(app.TimesTablesUIFigure);
            app.Question.Text = '';
            app.EditField = uieditfield(app.TimesTablesUIFigure, 'text');
            app.WhatisLabel = uilabel(app.TimesTablesUIFigure);
            app.WhatisLabel.Text = 'What is';
            app.OKButton = uibutton(app.TimesTablesUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @checkAnswer, true);
            app.OKButton.Text = 'OK';
            app.CloseButton = uibutton(app.TimesTablesUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @userQuit, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.TimesTablesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timestable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TimesTablesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @newGame)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimesTablesUIFigure)
        end
    end
end